classdef YouBotApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        CalculateScoreButton  matlab.ui.control.Button
        PercentCorrectLabel   matlab.ui.control.NumericEditField
        PercentofenvironmentscannedCorrectlyEditFieldLabel  matlab.ui.control.Label
        RandomMovementButton  matlab.ui.control.Button
        ActionLabel           matlab.ui.control.Label
        EndSimulationButton   matlab.ui.control.Button
        Directions            matlab.ui.control.Label
    end


    properties (Access = public)
        clientID
        sim remApi
        h
        open
        sensorHandle
        LaserTimer timer
        map
        vision
        mapInit logical = false;          
        continueExecute
        Running logical = false;
        TimerObj timer
        LogOddsMap
        inputVelocity
        inputAngularVelocity
        inputStop logical = false;
        sicko_handle = -1
        youBotHandle = -1
        LastCommand = ''
        step = 0
        TimerRandom timer
        TimerVisionScore timer

        currentTheta = 0
        dt = 0.6
        currentDistance = 0
        Robot_x = 0
        Robot_y = 0        
        Velocity = [0,0]
        angularVelocity = [0,0,0]
        inputRotationDirection = 0
        inputTranslationDirection = 0
        randomDirection string
        num_obstacles = 0;

        sensorPosition = [0,0,0];
        LastXScan
        LastYScan

        slam
        scan_count = 0
        loadedGrid
    end
    
    methods (Access = private)             
        function stepSimulation(app)
            if app.Running
                UpdateState(app);
                PlotState(app);
                NextAction(app);
                app.sim.simxSynchronousTrigger(app.clientID);
            end            
        end
        function stopSimulation(app)

            stop(app.LaserTimer);
            stop(app.TimerObj);
            stop(app.TimerRandom);

            delete(app.LaserTimer);  
            delete(app.TimerObj);   
            delete(app.TimerRandom);

            stop(app.TimerVisionScore);
            delete(app.TimerVisionScore);   

            app.sim.simxFinish(app.clientID);
            app.sim.simxStopSimulation(app.clientID,app.sim.simx_opmode_oneshot);
            close all force;          
        end 
        function getRandomDirection(app)
            positions = ["forward", "right", "left"];
            weights = [2, 1, 1];
            randomPosition = randsample(positions, 1, true, weights);
            app.randomDirection = randomPosition(1,1);
        end
        function setVisionScore(app)
            %values closer to 1 represent higher accuracy            
          
           if ~app.mapInit
                return;
            end
            
            %values closer to 1 represent higher accuracy
            occupancy = app.LogOddsMap.getOccupancy;
            loadedOccupancy = app.loadedGrid;   

            grid1_resized = imresize(loadedOccupancy, size(occupancy), 'bilinear');  % Adjust interpolation as needed

            min_val = min([grid1_resized(:); occupancy(:)]);
            max_val = max([grid1_resized(:); occupancy(:)]);
            bins = linspace(min_val, max_val, 20); % Adjust the number of bins as needed
             
            [counts1, ~] = histcounts(grid1_resized(:), bins, 'Normalization', 'probability');
            [counts2, ~] = histcounts(occupancy(:), bins, 'Normalization', 'probability');

            similarity_score = 1 - sum(abs(counts1 - counts2));  % Closer to 1 is more similar
            similarity_score = abs((similarity_score-.85)/.15); %attempt to squeeze probabilities since we know the maps will overlap 85% of the surface area

            app.PercentCorrectLabel.Value = similarity_score;
        end

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)


            %inital visibility rules
            app.Directions.Visible = "off";
            app.EndSimulationButton.Visible = "off";
            app.ActionLabel.Visible = "off";
            app.mapInit = false;
            app.sim = remApi('remoteApi');
            app.sim.simxFinish(-1);
            app.clientID = app.sim.simxStart('127.0.0.1',19999,true,true,5000,5);             
            app.h = [0,0,0,0,0,0,0,0,0,0,0]; % 11 total joints
            app.open = true;
            if (app.clientID>-1)
                load('Ideal_Map.mat'); %this holds the variable for scoring our vision
                app.loadedGrid = gridData;
                %could do something here. Not sure what i would do
                %success visibile rules
                r = app.sim.simxSynchronous(app.clientID, true);
                if r ~= 0
                  error('Failed to enable synchronous mode');
                end
                r = app.sim.simxStartSimulation(app.clientID, app.sim.simx_opmode_oneshot);
                app.sim.simxSynchronousTrigger(app.clientID);
                [~,app.h(1)] = app.sim.simxGetObjectHandle(app.clientID, 'rollingJoint_fl', app.sim.simx_opmode_blocking); % r is like a return code I think
                [~,app.h(2)] = app.sim.simxGetObjectHandle(app.clientID, 'rollingJoint_rl', app.sim.simx_opmode_blocking);
                [~,app.h(3)] = app.sim.simxGetObjectHandle(app.clientID, 'rollingJoint_rr', app.sim.simx_opmode_blocking); 
                [~,app.h(4)] = app.sim.simxGetObjectHandle(app.clientID, 'rollingJoint_fr', app.sim.simx_opmode_blocking);
                [~,app.youBotHandle] = app.sim.simxGetObjectHandle(app.clientID, 'youBot', app.sim.simx_opmode_blocking);                
                app.ActionLabel.Visible = false;
                app.Directions.Visible = "on";
                app.EndSimulationButton.Visible = "on";
                app.ActionLabel.Visible = "on";
                app.Running = true;
                
                app.TimerVisionScore = timer('ExecutionMode', 'fixedSpacing', ...
                         'Period', 30, ... % Step every 30
                         'TimerFcn', @(~,~) app.setVisionScore); 
                start(app.TimerVisionScore);
                app.TimerObj = timer('ExecutionMode', 'fixedSpacing', ...
                         'Period', 0.15, ... % Step every 0.2 sec
                         'TimerFcn', @(~,~) app.stepSimulation); 
                start(app.TimerObj);
                app.TimerRandom = timer('ExecutionMode', 'fixedSpacing', ...
                         'Period', 3, ... 
                         'TimerFcn', @(~,~) app.getRandomDirection); 
                start(app.TimerRandom);
                app.Running = true;
            else
                disp('Failed connecting to remote API server');
            end            
        end

        % Button pushed function: EndSimulationButton
        function EndSimulationButtonPushed(app, event)
            %end the simulation
            if app.clientID > -1
                stopSimulation(app);
                close all force;
            end

        end

        % Key press function: UIFigure
        function UIFigureKeyPress(app, event)
            key = event.Key;
            if app.clientID > -1
                key = event.Key;
                value = "nil";
                switch key
                    case {'leftarrow',"A"}
                        app.inputRotationDirection = -1;
                        app.LastCommand = 'Rotate';
                        value = "Rotating counter-clockwise";

                    case {'rightarrow', "D"}
                        app.inputRotationDirection = 1;
                        app.LastCommand = 'Rotate';
                        value = "Rotating clockwise";

                    case {'uparrow', "W"}
                        app.inputTranslationDirection = 1;
                        app.LastCommand = 'Translate';
                        value = "Moving Forward";

                    case {'downarrow', "S"}
                        app.inputTranslationDirection = -1;
                        app.LastCommand = 'Translate';
                        value = "Moving Backward";

                    case {'space',"X"}
                        app.LastCommand = 'Stop';
                        value = "Staying in place";
                    otherwise

                        % Co    de for other keys
                        disp(['Key pressed: ', key]);
                        value = strcat("Unknown Key: ",key);
                end
                app.ActionLabel.Text = value;
                app.ActionLabel.Visible = true;
            end
        end

        % Button pushed function: RandomMovementButton
        function RandomMovementButtonPushed(app, event)
            if app.RandomMovementButton.BackgroundColor == [0.96,0.96,0.96]
                app.RandomMovementButton.BackgroundColor = [0, 1.00, 1.00];
            else
                app.RandomMovementButton.BackgroundColor = [0.96,0.96,0.96];
            end
        end

        % Button pushed function: CalculateScoreButton
        function CalculateScoreButtonPushed(app, event)

            if ~app.mapInit
                return;
            end
            
            %values closer to 1 represent higher accuracy
            occupancy = app.LogOddsMap.getOccupancy;
            loadedOccupancy = app.loadedGrid;   

            grid1_resized = imresize(loadedOccupancy, size(occupancy), 'bilinear');  % Adjust interpolation as needed

            min_val = min([grid1_resized(:); occupancy(:)]);
            max_val = max([grid1_resized(:); occupancy(:)]);
            bins = linspace(min_val, max_val, 20); % Adjust the number of bins as needed
             
            [counts1, ~] = histcounts(grid1_resized(:), bins, 'Normalization', 'probability');
            [counts2, ~] = histcounts(occupancy(:), bins, 'Normalization', 'probability');

            similarity_score = 1 - sum(abs(counts1 - counts2));  % Closer to 1 is more similar
            similarity_score = abs((similarity_score-.85)/.15); %attempt to squeeze probabilities since we know the maps will overlap 85% of the surface area

            app.PercentCorrectLabel.Value = similarity_score;

            % total_number_obstacles = 0;
            % total_non_obstacles = 0;
            % for i = 1:1000
            %     for j = 1:500  
            %         if app.LogOddsMap(j,i) >= 12 && app.idealMap(j,i) >= 1
            %             obstacles_right = obstacles_right + 1;
            %         elseif app.LogOddsMap(j,i) < 12 && app.idealMap(j,i) >= 1
            %             missed_obstacle = missed_obstacle + 1;
            %         elseif app.LogOddsMap(j,i) >= 12 && app.idealMap(j,i) < 1
            %              not_an_obstacle_wrong = not_an_obstacle_wrong + 1;
            %         elseif app.LogOddsMap(j,i) < 12 && app.idealMap(j,i) < 1
            %              not_an_obstacle_correct = not_an_obstacle_correct + 1;
            %         else
            %             print('Check case');
            %         end
            % 
            %         if app.idealMap(j,i) >= 1
            %             total_number_obstacles = total_number_obstacles + 1;
            %         else
            %             total_non_obstacles = total_non_obstacles + 1;
            %         end
            % 
            %     end
            % end
            % total_score = (obstacles_right + not_an_obstacle_correct)/(not_an_obstacle_wrong + not_an_obstacle_correct + obstacles_right + missed_obstacle);
            % app.PercentCorrectLabel.Value = obstacles_right/total_number_obstacles;
            % app.PercentIncorrectLabel.Value = 1- total_score;


        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.KeyPressFcn = createCallbackFcn(app, @UIFigureKeyPress, true);
            app.Directions = uilabel(app.UIFigure);
            app.Directions.Text = {'Left Arrow - Rotate Left'; 'Right Arrow - Rotate Right'; 'Up Arrow - Forward'; 'Down Arrow - Backward'; 'Spacebar - Stop in place'};
            app.EndSimulationButton = uibutton(app.UIFigure, 'push');
            app.EndSimulationButton.ButtonPushedFcn = createCallbackFcn(app, @EndSimulationButtonPushed, true);
            app.EndSimulationButton.Text = 'End Simulation';
            app.ActionLabel = uilabel(app.UIFigure);
            app.ActionLabel.Text = '';
            app.RandomMovementButton = uibutton(app.UIFigure, 'push');
            app.RandomMovementButton.ButtonPushedFcn = createCallbackFcn(app, @RandomMovementButtonPushed, true);
            app.RandomMovementButton.Text = 'Random Movement';
            app.PercentofenvironmentscannedCorrectlyEditFieldLabel = uilabel(app.UIFigure);
            app.PercentofenvironmentscannedCorrectlyEditFieldLabel.Text = 'Percent of environment scanned Correctly:';
            app.PercentCorrectLabel = uieditfield(app.UIFigure, 'numeric');
            app.PercentCorrectLabel.Limits = [0 1];
            app.PercentCorrectLabel.ValueDisplayFormat = '%5.2f';
            app.CalculateScoreButton = uibutton(app.UIFigure, 'push');
            app.CalculateScoreButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateScoreButtonPushed, true);
            app.CalculateScoreButton.Text = 'Calculate Score';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = YouBotApp

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.UIFigure)

                % Execute the startup function
                runStartupFcn(app, @startupFcn)
            else

                % Focus the running singleton app
                figure(runningApp.UIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end